classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DesenaregraficeButton           matlab.ui.control.Button
        SelectareaFunctieiListBox       matlab.ui.control.ListBox
        SelectareaFunctieiListBoxLabel  matlab.ui.control.Label
        SelectareaculoriiDropDown       matlab.ui.control.DropDown
        SelectareaculoriiDropDownLabel  matlab.ui.control.Label
        szEditField                     matlab.ui.control.EditField
        szEditFieldLabel                matlab.ui.control.Label
        zEditField                      matlab.ui.control.EditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.EditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.EditField
        xEditFieldLabel                 matlab.ui.control.Label
        FunctiileBubblechartLabel       matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x=[]; y=[]; z=[]; sz=[];
        c='k';% Description
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xEditField.Value='1:20';
            app.yEditField.Value='1:20';
            app.szEditField.Value='1:20';
            app.zEditField.Value='1:20';
        end

        % Button pushed function: DesenaregraficeButton
        function DesenaregraficeButtonPushed(app, event)
           app.x=str2num(app.xEditField.Value);
           app.y=str2num(app.yEditField.Value);
           app.z=str2num(app.zEditField.Value);
           app.sz=str2num(app.szEditField.Value);

           val=app.SelectareaFunctieiListBox.Value;

           switch val
               case 'Bubblechart'
                   cla(app.UIAxes2);
                   bubblechart(app.UIAxes, app.x, app.y, app.sz);
               case 'Bubblechart (Culoare)'
                   cla(app.UIAxes2);
                   bubblechart(app.UIAxes, app.x, app.y, app.sz, app.c);
               case 'Bubblechart3'
                   cla(app.UIAxes);
                   bubblechart3(app.UIAxes2, app.x,app.y,app.z,app.sz);
               case 'Bubblechart3 (Culoare)'
                   cla(app.UIAxes);
                   bubblechart3(app.UIAxes2, app.x,app.y,app.z,app.sz, app.c);
           end
        end

        % Value changed function: SelectareaculoriiDropDown
        function SelectareaculoriiDropDownValueChanged(app, event)
            value = app.SelectareaculoriiDropDown.Value;
            
            if value == "Yellow"
                app.c = 'y';
            elseif value == "Black"
                app.c = 'k';
            elseif value == "Green"
                app.c = 'g';
            elseif value == "Blue"
                app.c = 'b';
            elseif value == "Red"
                app.c = 'r';
            end
        end

        % Value changed function: SelectareaFunctieiListBox
        function SelectareaFunctieiListBoxValueChanged(app, event)
            value = app.SelectareaFunctieiListBox.Value;
            
            app.zEditField.Enable = ~(value == "Bubblechart" || value == "Bubblechart (Culoare)");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plan')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spatiu')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.FunctiileBubblechartLabel = uilabel(app.UIFigure);
            app.FunctiileBubblechartLabel.Text = 'Functiile Bubblechart';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x=';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.xEditField.Value = '7';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y=';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.yEditField.Value = '8';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z=';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            app.zEditField.Enable = 'off';
            app.zEditField.Value = '9';
            app.szEditFieldLabel = uilabel(app.UIFigure);
            app.szEditFieldLabel.Text = 'sz=';
            app.szEditField = uieditfield(app.UIFigure, 'text');
            app.szEditField.Value = '2';
            app.SelectareaculoriiDropDownLabel = uilabel(app.UIFigure);
            app.SelectareaculoriiDropDownLabel.Text = 'Selectarea culorii';
            app.SelectareaculoriiDropDown = uidropdown(app.UIFigure);
            app.SelectareaculoriiDropDown.Items = {'Black', 'Green', 'Red', 'Yellow', 'Blue'};
            app.SelectareaculoriiDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectareaculoriiDropDownValueChanged, true);
            app.SelectareaculoriiDropDown.Value = 'Black';
            app.SelectareaFunctieiListBoxLabel = uilabel(app.UIFigure);
            app.SelectareaFunctieiListBoxLabel.Text = 'Selectarea Functiei';
            app.SelectareaFunctieiListBox = uilistbox(app.UIFigure);
            app.SelectareaFunctieiListBox.Items = {'Bubblechart', 'Bubblechart (Culoare)', 'Bubblechart3', 'Bubblechart3 (Culoare)'};
            app.SelectareaFunctieiListBox.ValueChangedFcn = createCallbackFcn(app, @SelectareaFunctieiListBoxValueChanged, true);
            app.SelectareaFunctieiListBox.Value = 'Bubblechart';
            app.DesenaregraficeButton = uibutton(app.UIFigure, 'push');
            app.DesenaregraficeButton.ButtonPushedFcn = createCallbackFcn(app, @DesenaregraficeButtonPushed, true);
            app.DesenaregraficeButton.Text = 'Desenare grafice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end